classdef Blackjack < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        WelcomeButton           matlab.ui.control.Button
        WelcomeImage            matlab.ui.control.Image
        TieImage                matlab.ui.control.Image
        HitBlackjack            matlab.ui.control.Image
        LoserImage              matlab.ui.control.Image
        WinnerImage             matlab.ui.control.Image
        NotEnoughMoney          matlab.ui.control.Image
        PlayerScoreLabel        matlab.ui.control.Label
        Label32                 matlab.ui.control.Label
        PCard11                 matlab.ui.control.Image
        PCard10                 matlab.ui.control.Image
        PCard9                  matlab.ui.control.Image
        PCard8                  matlab.ui.control.Image
        DCard11                 matlab.ui.control.Image
        DCard10                 matlab.ui.control.Image
        DCard9                  matlab.ui.control.Image
        DCard8                  matlab.ui.control.Image
        Money                   matlab.ui.control.Label
        PlayerScore             matlab.ui.control.Label
        DealersScore            matlab.ui.control.Label
        MoneyLabel              matlab.ui.control.Label
        DCard7                  matlab.ui.control.Image
        DCard6                  matlab.ui.control.Image
        DCard5                  matlab.ui.control.Image
        PCard7                  matlab.ui.control.Image
        PCard6                  matlab.ui.control.Image
        PCard5                  matlab.ui.control.Image
        StartButton             matlab.ui.control.Button
        DCard4                  matlab.ui.control.Image
        DCard3                  matlab.ui.control.Image
        DCard2                  matlab.ui.control.Image
        PCard4                  matlab.ui.control.Image
        PCard3                  matlab.ui.control.Image
        PCard2                  matlab.ui.control.Image
        PCard1                  matlab.ui.control.Image
        DCard1                  matlab.ui.control.Image
        DealersScoreLabel       matlab.ui.control.Label
        StandButton             matlab.ui.control.Button
        HitButton               matlab.ui.control.Button
        PushToConfirmBetButton  matlab.ui.control.Button
        BetSlider               matlab.ui.control.Slider
        Background              matlab.ui.control.Image
    end

    
    %Zach Rudder and Tyler Chaudhary
    %ENGR 1050 Final Project
    %Blackjack -- 12/20/22
    properties (Access = private)
        
        %Create 52 card deck
        deck = 1:52; 

        %Initialize the card count for player and dealer
        pCount = 0;
        dCount = 0;

        %Initizalize player hand and dealer hand
        playerHand = 0;
        dealerHand = 0;

        %Initialize the cards that they have
        playerCards = [];
        playerImages = [""];
        dealerCards = [];
        dealerImages = [""]; 

        %Initialize the bet placed and start user with $5000     
        bet = 0;
        money = 5000;

    end
    
    methods (Access = private)
        
      function [card,image,updatedDeck] = drawCard(app,deck,currentHand)
        %Choose a random card from the card deck. Inputs are the value of 
        % the current hand and the current deck.Outputs the value and image
        % file name of a random card.

        %Pick a random card from the deck
        choice = randi(length(deck));

        %If that card is already in play, pick another card
        while deck(choice) == 0
            choice = randi(length(deck));
        end

        %Sent the new card equal to zero
        deck(choice) = 0;
        updatedDeck = deck;

        %Depending on what position the card was in the original deck, get the
        %value and info about that card
        if choice == 1

            %For an ace, if a value of 11 would make the hand go over 21,
            %set the value to 1
            if (currentHand + 11) > 21
                card = 1;
            else
                card = 11;
            end            
            image = ("AClubs.png");

        elseif choice == 2

            if (currentHand + 11) > 21
                card = 1;
            else
                card = 11;
            end
            image = ("ADiamonds.png");

        elseif choice == 3

            if (currentHand + 11) > 21
                card = 1;
            else
                card = 11;
            end
            image = ("AHearts.png");

        elseif choice == 4

            if (currentHand + 11) > 21
                card = 1;
            else
                card = 11;
            end
            image = ("ASpades.png");

        elseif choice == 5

            card = 2;
            image = ("2Clubs.png");

        elseif choice == 6

            card = 2;
            image = ("2Diamonds.png");

        elseif choice == 7

            card = 2;
            image = ("2Hearts.png");

        elseif choice == 8

            card = 2;
            image = ("2Spades.png");

        elseif choice == 9

            card = 3;
            image = ("3Clubs.png");

        elseif choice == 10

            card = 3;
            image = ("3Diamonds.png");

        elseif choice == 11

            card = 3;
            image = ("3Hearts.png");

        elseif choice == 12

            card = 3;
            image = ("3Spades.png");

        elseif choice == 13

            card = 4;
            image = ("4Clubs.png");

        elseif choice == 14

            card = 4;
            image = ("4Diamonds.png");

        elseif choice == 15

            card = 4;
            image = ("4Hearts.png");

        elseif choice == 16

            card = 4;
            image = ("4Spades.png");

        elseif choice == 17

            card = 5;
            image = ("5Clubs.png");

        elseif choice == 18

            card = 5;
            image = ("5Diamonds.png");

        elseif choice == 19

            card = 5;
            image = ("5Hearts.png");

        elseif choice == 20

            card = 5;
            image = ("5Spades.png");

        elseif choice == 21

            card = 6;
            image = ("6Clubs.png");

        elseif choice == 22

            card = 6;
            image = ("6Diamonds.png");

        elseif choice == 23

            card = 6;
            image = ("6Hearts.png");

        elseif choice == 24

            card = 6;
            image = ("6Spades.png");

        elseif choice == 25

            card = 7;
            image = ("7Clubs.png");

        elseif choice == 26

            card = 7;
            image = ("7Diamonds.png");

        elseif choice == 27

            card = 7;
            image = ("7Hearts.png");

        elseif choice == 28

            card = 7;
            image = ("7Spades.png");

        elseif choice == 29

            card = 8;
            image = ("8Clubs.png");

        elseif choice == 30

            card = 8;
            image = ("8Diamonds.png");

        elseif choice == 31

            card = 8;
            image = ("8Hearts.png");

        elseif choice == 32

            card = 8;
            image = ("8Spades.png");

        elseif choice == 33

            card = 9;
            image = ("9Clubs.png");

        elseif choice == 34

            card = 9;
            image = ("9Diamonds.png");

        elseif choice ==35

            card = 9;
            image = ("9Hearts.png");

        elseif choice == 36

            card = 9;
            image = ("9Spades.png");

        elseif choice == 37

            card = 10;
            image = ("10Clubs.png");

        elseif choice == 38

            card = 10;
            image = ("10Diamonds.png");

        elseif choice == 39

            card = 10;
            image = ("10Hearts.png");

        elseif choice == 40

            card = 10;
            image = ("10Spades.png");

        elseif choice == 41

            card = 10;
            image = ("JClubs.png");

        elseif choice == 42

            card = 10;
            image = ("JDiamonds.png");

        elseif choice == 43

            card = 10;
            image = ("JHearts.png");

        elseif choice == 44

            card = 10;
            image = ("JSpades.png");

        elseif choice == 45

            card = 10;
            image = ("QClubs.png");

        elseif choice == 46

            card = 10;
            image = ("QDiamonds.png");

        elseif choice == 47

            card = 10;
            image = ("QHearts.png");

        elseif choice == 48

            card = 10;
            image = ("QSpades.png");

        elseif choice == 49

            card = 10;
            image = ("KClubs.png");

        elseif choice == 50

            card = 10;
            image = ("KDiamonds.png");

        elseif choice == 51

            card = 10;
            image = ("KHearts.png");

        elseif choice == 52

            card = 10;
            image = ("KSpades.png");
        end

      end
        
        %Resets the screen and the variables after each hand
        function reset(app)
           
            %Reset the starting screen
            clearCardImages(app);
            clearScreen(app);
            startTurnScreen(app);

            %Create 52 card deck
            app.deck = 1:52; 

            %Initialize the card count for player and dealer
            app.pCount = 0;
            app.dCount = 0;

            %Initizalize player hand and dealer hand
            app.playerHand = 0;
            app.dealerHand = 0;

            %Initialize the cards that they have
            app.playerCards = [];
            app.playerImages = [""];
            app.dealerCards = [];
            app.dealerImages = [""];

            %Update values on screen            
            app.PlayerScore.Text = num2str(app.playerHand);
            app.DealersScore.Text = num2str(app.dealerHand);         
     
        end
        
        %Logic to be used when a player chooses to stand or if a player is
        %dealt blackjack
        function StandLogic(app)
             
            %Per regular Blackjack rules, the dealer has to hit until they
            %get a hand value of 17 or higher
            while app.dealerHand < 17

                %Deal the dealer a card
                [app.dealerCards(end+1),app.dealerImages(end+1),app.deck] = drawCard(app,app.deck,app.dealerHand);
                app.dealerHand = sum(app.dealerCards);
                app.DealersScore.Text = num2str(app.dealerHand);
                app.dCount = app.dCount + 1;
                
                %Depending on what number card it is for the dealer,
                %display that image
                if app.dCount == 2
                     app.DCard2.ImageSource = app.dealerImages(2);
                     app.DCard2.Visible = 'on';
                elseif app.dCount == 3
                     app.DCard3.ImageSource = app.dealerImages(3);
                     app.DCard3.Visible = 'on';
                elseif app.dCount == 4
                     app.DCard4.ImageSource = app.dealerImages(4);
                     app.DCard4.Visible = 'on';
                elseif app.dCount == 5
                     app.DCard5.ImageSource = app.dealerImages(5);
                     app.DCard5.Visible = 'on';
                elseif app.dCount == 6
                     app.DCard6.ImageSource = app.dealerImages(6);
                     app.DCard6.Visible = 'on';
                elseif app.dCount == 7
                     app.DCard7.ImageSource = app.dealerImages(7);
                     app.DCard7.Visible = 'on';
                elseif app.dCount == 8
                     app.DCard8.ImageSource = app.dealerImages(8);
                     app.DCard8.Visible = 'on';
                elseif app.dCount == 9
                     app.DCard9.ImageSource = app.dealerImages(9);
                     app.DCard9.Visible = 'on';
                elseif app.dCount == 10
                     app.DCard10.ImageSource = app.dealerImages(10);
                     app.DCard10.Visible = 'on';
                elseif app.dCount == 11
                     app.DCard11.ImageSource = app.dealerImages(11);
                     app.DCard11.Visible = 'on';
                end

                %Checking if the dealer has an ace
                for i = 1:length(app.dealerCards)

                    if app.dealerCards(i) == 11

                        %If dealer has an ace equal to 11 and the hand is over 21,
                        %makes that ace equal to 1 and re-sum the dealer hand
                        if app.dealerHand > 21
                            app.dealerCards(i) = 1;
                            app.dealerHand = sum(app.dealerCards);
                            app.DealersScore.Text = num2str(app.dealerHand);
                        end
                    end
                end
                pause(1);
            end
                
                %Different possible outcomes depending on the player's and
                %the dealer's hands.The player's money gets updated, the 
                %outcome image is displayed and the game rests
                if app.dealerHand > 21                    
                    pause(1);                   
                    app.WinnerImage.Visible = 'on';
                    app.money = app.money + app.bet;
                    app.Money.Text = num2str(app.money);
                    pause(3);
                    reset(app);
                    return 

                elseif app.dealerHand <= 21
                    if app.playerHand > app.dealerHand
                        pause(1);
                        app.WinnerImage.Visible = 'on';
                        app.money = app.money + app.bet;
                        app.Money.Text = num2str(app.money);
                        pause(3);
                        reset(app); 
                        return

                    elseif app.dealerHand > app.playerHand
                        pause(1);
                        app.LoserImage.Visible = 'on';
                        app.money = app.money - app.bet;
                        app.Money.Text = num2str(app.money);
                        pause(3);
                        reset(app);
                        return

                    elseif app.playerHand == app.dealerHand
                        pause(1);
                        app.TieImage.Visible = 'on';
                        app.Money.Text = num2str(app.money);
                        pause(3);
                        reset(app);
                        return
                    end
                end                    
        end
        
        %Turn off all the card images from the display
        function clearCardImages(app)

            app.PCard1.Visible = 'off';
            app.PCard2.Visible = 'off';
            app.PCard3.Visible = 'off';
            app.PCard4.Visible = 'off';
            app.PCard5.Visible = 'off';
            app.PCard6.Visible = 'off';
            app.PCard7.Visible = 'off';
            app.PCard8.Visible = 'off';
            app.PCard9.Visible = 'off';
            app.PCard10.Visible = 'off';
            app.PCard11.Visible = 'off';
            app.DCard1.Visible = 'off';
            app.DCard2.Visible = 'off';
            app.DCard3.Visible = 'off';
            app.DCard4.Visible = 'off';
            app.DCard5.Visible = 'off';
            app.DCard6.Visible = 'off';
            app.DCard7.Visible = 'off';
            app.DCard8.Visible = 'off';
            app.DCard9.Visible = 'off';
            app.DCard10.Visible = 'off';
            app.DCard11.Visible = 'off';
            
        end
     
        %Clears everything from the screen besides the background
        function clearScreen(app)

            app.HitButton.Visible = 'off';
            app.StandButton.Visible = 'off';
            app.MoneyLabel.Visible = 'off';
            app.DealersScoreLabel.Visible = 'off';
            app.PlayerScoreLabel.Visible = 'off';
            app.StartButton.Visible = 'off';
            app.BetSlider.Visible = 'off';
            app.PushToConfirmBetButton.Visible = 'off';
            app.Money.Visible = 'off';
            app.DealersScore.Visible = 'off';
            app.PlayerScore.Visible = 'off';
            app.WelcomeButton.Visible = 'off';
            app.WelcomeImage.Visible = 'off';            
            app.TieImage.Visible = 'off';
            app.LoserImage.Visible = 'off';
            app.WinnerImage.Visible = 'off';
            app.HitBlackjack.Visible = 'off';
            app.NotEnoughMoney.Visible = 'off';
            app.Label32.Visible = 'off';
            
        end
        
        %Displays the components that are needed to start a round
        function startTurnScreen(app)

            app.StartButton.Visible = 'on';
            app.BetSlider.Visible = 'on';
            app.PushToConfirmBetButton.Visible = 'on';
            app.Money.Visible = 'on';
            app.MoneyLabel.Visible = 'on';
            app.Label32.Visible = 'on';   

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HitButton
        function HitButtonPushed(app, event)
            
            %Deal the player a card
            [app.playerCards(end+1),app.playerImages(end+1),app.deck] = drawCard(app,app.deck,app.playerHand);
            app.playerHand = sum(app.playerCards);
            app.PlayerScore.Text = num2str(app.playerHand);
            app.pCount = app.pCount + 1;                
                
            %Determining which card to make visible
                if app.pCount == 3
                     app.PCard3.ImageSource = app.playerImages(3);
                     app.PCard3.Visible = 'on';
                elseif app.pCount == 4
                     app.PCard4.ImageSource = app.playerImages(4);
                     app.PCard4.Visible = 'on';
                elseif app.pCount == 5
                     app.PCard5.ImageSource = app.playerImages(5);
                     app.PCard5.Visible = 'on';
                elseif app.pCount == 6
                     app.PCard6.ImageSource = app.playerImages(6);
                     app.PCard6.Visible = 'on';
                elseif app.pCount == 7
                     app.PCard7.ImageSource = app.playerImages(7);
                     app.PCard7.Visible = 'on';
                elseif app.pCount == 8
                     app.PCard8.ImageSource = app.playerImages(8);
                     app.PCard8.Visible = 'on';
                elseif app.pCount == 9
                     app.PCard9.ImageSource = app.playerImages(9);
                     app.PCard9.Visible = 'on';
                elseif app.pCount == 10
                     app.PCard10.ImageSource = app.playerImages(10);
                     app.PCard10.Visible = 'on';
                elseif app.pCount == 11
                     app.PCard11.ImageSource = app.playerImages(11);
                     app.PCard11.Visible = 'on';
                end
            
            %If the player hits 21, the dealer now has to play out their
            %turn
            if app.playerHand == 21
                pause(1);
                StandLogic(app);
            end

            %Checking if the player has an ace
            for i = 1:length(app.playerCards)

                if app.playerCards(i) == 11

                    %If player has an ace equal to 11 and the hand is over 21,
                    %makes that ace equal to 1 and re-sum the players hand
                    if app.playerHand > 21
                        app.playerCards(i) = 1;
                        app.playerHand = sum(app.playerCards);
                        app.PlayerScore.Text = num2str(app.playerHand);
                    end
                end
            end
            
            %If the player busts, they lose. The players money gets updated
            %and the game rests
            if app.playerHand > 21                
                pause(2); 
                app.LoserImage.Visible = 'on';
                app.money = app.money - app.bet;
                app.Money.Text = num2str(app.money);
                pause(3);
                reset(app);
                return
            end
        end

        % Button pushed function: StandButton
        function StandButtonPushed(app, event)
            pause(1);
            StandLogic(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            %Clear the starting screen and set up the scores and buttons            
            app.PushToConfirmBetButton.Visible = 'off';
            app.BetSlider.Visible = 'on';
            app.StartButton.Visible = 'off';
            app.MoneyLabel.Visible = 'on';
            app.DealersScoreLabel.Visible = 'on';
            app.PlayerScoreLabel.Visible = 'on'; 
            app.HitButton.Visible = 'off';
            app.StandButton.Visible = 'off';
            app.Money.Visible = 'on';
            app.DealersScore.Visible = 'on';
            app.PlayerScore.Visible = 'on';
            app.Money.Text = num2str(app.money);           
            pause(2)

            %Deal player two cards
            [app.playerCards(1),app.playerImages(1),app.deck] = drawCard(app,app.deck,app.playerHand);
            app.PCard1.ImageSource = app.playerImages(1);
            app.PCard1.Visible = 'on';
            app.pCount = 1;
            pause(1);

            [app.playerCards(2),app.playerImages(2),app.deck] = drawCard(app,app.deck,app.playerHand);
            app.PCard2.ImageSource = app.playerImages(2);
            app.PCard2.Visible = 'on';
            app.pCount = 2;

            %Update the players hand
            app.playerHand = sum(app.playerCards);
            
            %Display players score
            app.PlayerScore.Text = num2str(app.playerHand);

            pause(1);
            %Deal the dealer one card
            [app.dealerCards(1),app.dealerImages(1),app.deck] = drawCard(app,app.deck,app.dealerHand);
            app.DCard1.ImageSource = app.dealerImages(1);
            app.DCard1.Visible = 'on';
            app.dCount = 1;

            pause(1);
            %Dealer's second card is not facing up
            app.DCard2.ImageSource = 'BackCARD.png';
            app.DCard2.Visible = 'on';

            %Update dealers hand
            app.dealerHand = sum(app.dealerCards);

            %Display dealers score
            app.DealersScore.Text = num2str(app.dealerHand);

            pause(1);
            %If player is dealt Blackjack, and dealer does not have Blackjack
            %they win and payout is 1.5 times the bet
            if app.playerHand == 21

                %Dealer's first card must be a 10 or 11 to have a chance at
                %Blackjack
                if app.dealerHand == 10 || app.dealerHand == 11
                    [app.dealerCards(2),app.dealerImages(2),app.deck] = drawCard(app,app.deck,app.dealerHand);
                    app.DCard2.ImageSource = app.dealerImages(2);
                    app.DCard2.Visible = 'on';
                    app.dealerHand = sum(app.dealerCards);
                    app.DealersScore.Text = num2str(app.dealerHand);
                    app.dCount = 2;

                    %Displays the outcome of the game, updates the
                    %players money and resets the game
                    if app.dealerHand == 21
                        pause(2);
                        app.TieImage.Visible = 'on';
                        app.Money.Text = num2str(app.money);
                        pause(3);
                        reset(app);
                        return
                    else                        
                        pause(2);
                        app.WinnerImage.Visible = 'on';
                        app.HitBlackjack.Visible = 'on';
                        app.money = app.money + (app.bet*1.5);
                        app.Money.Text = num2str(app.money);
                        pause(3);
                        reset(app);
                        return
                    end
                else                    
                    pause(2);
                    app.WinnerImage.Visible = 'on';
                    app.HitBlackjack.Visible = 'on';
                    app.money = app.money + (app.bet*1.5);
                    app.Money.Text = num2str(app.money);
                    pause(3);
                    reset(app);
                    return
                end
            end

            %Checking if the player has an ace
            for i = 1:length(app.playerCards)

                if app.playerCards(i) == 11

                    %If player has an ace equal to 11 and the hand is over 21,
                    %makes that ace equal to 1 and re-sum the players hand
                    if app.playerHand > 21
                        app.playerCards(i) = 1;
                        app.playerHand = sum(app.playerCards);
                        app.PlayerScore.Text = num2str(app.playerHand);
                    end
                end
            end

            %Allow user to hit or stand
            app.HitButton.Visible = 'on';
            app.StandButton.Visible = 'on';
            
        end

        % Button pushed function: WelcomeButton
        function WelcomeButtonPushed(app, event)
            clearCardImages(app);
            clearScreen(app);
            startTurnScreen(app);
        end

        % Button pushed function: PushToConfirmBetButton
        function PushToConfirmBetButtonPushed(app, event)
            
             %If user tries to bet more money than they have, display the
             %error image and make them choose a lower bet
             if app.BetSlider.Value > app.money
                app.NotEnoughMoney.Visible = 'on';
                pause(2);
                app.NotEnoughMoney.Visible = 'off';
             else
                app.bet = round(app.BetSlider.Value);
                app.NotEnoughMoney.Visible = 'off';
                app.PushToConfirmBetButton.Visible = 'off';
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Background = uiimage(app.UIFigure);
            app.Background.ImageSource = fullfile(pathToMLAPP, 'blackjackTable.jpg');
            app.BetSlider = uislider(app.UIFigure);
            app.BetSlider.Limits = [0 5000];
            app.PushToConfirmBetButton = uibutton(app.UIFigure, 'push');
            app.PushToConfirmBetButton.ButtonPushedFcn = createCallbackFcn(app, @PushToConfirmBetButtonPushed, true);
            app.PushToConfirmBetButton.Text = 'Push To Confirm Bet';
            app.HitButton = uibutton(app.UIFigure, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushed, true);
            app.HitButton.Text = 'Hit';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
            app.StandButton.Text = 'Stand';
            app.DealersScoreLabel = uilabel(app.UIFigure);
            app.DealersScoreLabel.Text = 'Dealer''s Score: ';
            app.DCard1 = uiimage(app.UIFigure);
            app.PCard1 = uiimage(app.UIFigure);
            app.PCard2 = uiimage(app.UIFigure);
            app.PCard3 = uiimage(app.UIFigure);
            app.PCard4 = uiimage(app.UIFigure);
            app.DCard2 = uiimage(app.UIFigure);
            app.DCard3 = uiimage(app.UIFigure);
            app.DCard4 = uiimage(app.UIFigure);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PCard5 = uiimage(app.UIFigure);
            app.PCard6 = uiimage(app.UIFigure);
            app.PCard7 = uiimage(app.UIFigure);
            app.DCard5 = uiimage(app.UIFigure);
            app.DCard6 = uiimage(app.UIFigure);
            app.DCard7 = uiimage(app.UIFigure);
            app.MoneyLabel = uilabel(app.UIFigure);
            app.MoneyLabel.Text = 'Money: $';
            app.DealersScore = uilabel(app.UIFigure);
            app.DealersScore.Text = '';
            app.PlayerScore = uilabel(app.UIFigure);
            app.PlayerScore.Text = '';
            app.Money = uilabel(app.UIFigure);
            app.Money.Text = '5000';
            app.DCard8 = uiimage(app.UIFigure);
            app.DCard9 = uiimage(app.UIFigure);
            app.DCard10 = uiimage(app.UIFigure);
            app.DCard11 = uiimage(app.UIFigure);
            app.PCard8 = uiimage(app.UIFigure);
            app.PCard9 = uiimage(app.UIFigure);
            app.PCard10 = uiimage(app.UIFigure);
            app.PCard11 = uiimage(app.UIFigure);
            app.Label32 = uilabel(app.UIFigure);
            app.Label32.Text = 'BLACKJACK PAYS 3:2';
            app.PlayerScoreLabel = uilabel(app.UIFigure);
            app.PlayerScoreLabel.Text = 'Your Score: ';
            app.NotEnoughMoney = uiimage(app.UIFigure);
            app.NotEnoughMoney.ImageSource = fullfile(pathToMLAPP, 'NotEnoughMoney.png');
            app.WinnerImage = uiimage(app.UIFigure);
            app.WinnerImage.ImageSource = fullfile(pathToMLAPP, 'Winner.jpg');
            app.LoserImage = uiimage(app.UIFigure);
            app.LoserImage.ImageSource = fullfile(pathToMLAPP, 'DealerWins.png');
            app.HitBlackjack = uiimage(app.UIFigure);
            app.HitBlackjack.ImageSource = fullfile(pathToMLAPP, 'HitBlackjack.jpeg');
            app.TieImage = uiimage(app.UIFigure);
            app.TieImage.ImageSource = fullfile(pathToMLAPP, 'Tie.jpg');
            app.WelcomeImage = uiimage(app.UIFigure);
            app.WelcomeImage.ImageSource = fullfile(pathToMLAPP, 'Welcome2.JPG');
            app.WelcomeButton = uibutton(app.UIFigure, 'push');
            app.WelcomeButton.ButtonPushedFcn = createCallbackFcn(app, @WelcomeButtonPushed, true);
            app.WelcomeButton.Text = 'PRESS TO ENTER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Blackjack

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end